classdef Interface_Cinematica_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PointsDropDownLabel          matlab.ui.control.Label
        PointsDropDown               matlab.ui.control.DropDown
        AngleDropDownLabel           matlab.ui.control.Label
        AngleDropDown                matlab.ui.control.DropDown
        WhatgraphicsdoyouwanttoseeLabel  matlab.ui.control.Label
        ShowAnimationCheckBox        matlab.ui.control.CheckBox
        KinematicProjectLabel        matlab.ui.control.Label
        EvaluateButton               matlab.ui.control.Button
        GetfileButton                matlab.ui.control.Button
        ChoosethemovementyouwanttoanaliseLabel  matlab.ui.control.Label
        PositionCheckBox_2           matlab.ui.control.CheckBox
        VelocityCheckBox             matlab.ui.control.CheckBox
        AccelerationCheckBox         matlab.ui.control.CheckBox
        AnglesCheckBox               matlab.ui.control.CheckBox
        AngularVelocityCheckBox      matlab.ui.control.CheckBox
        AngularAccelerationCheckBox  matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Button pushed function: GetfileButton
        function GetfileButtonPushed(app, event)
            global data 
            file=uigetfile('.xlsx');
            [~,~,data]=xlsread(file);


        end

        % Callback function: EvaluateButton, PointsDropDown
        function EvaluateButtonPushed(app, event)
            global data;
            global q qponto q2pontos P V A;
            global i
            global position velocity acceleration l
            global angles anglevel angleacc e
            [q, qponto, q2pontos, P, V,  A]=main2(data);
            
            if i==1
                animation (q)
            end
            
            if position==1
                print_position(q,A,l)
            end
            
            if velocity==1
                print_velocity(qponto,A,l)
            end
            
            if acceleration==1
                print_acceleration(q2pontos,A,l)
            end
            
            if angles==1
                print_angles(P,A,e)
            end
            
            if anglevel==1
                print_anglevel(V,A,e)
            end
            
            if angleacc==1
                print_angleacc(A,A,e)
            end
                
            clear i position velocity acceleration angles anglevel angleacc;
        end

        % Value changed function: ShowAnimationCheckBox
        function ShowAnimationCheckBoxValueChanged(app, event)
            value = app.ShowAnimationCheckBox.Value;
            disp('botão carregado')
            global i
            if i==1
                i=0;
            else
                i=1;
            end
            
        end

        % Value changed function: PointsDropDown
        function PointsDropDownValueChanged(app, event)
            value = app.PointsDropDown.Value;
            global l 
            switch value
                case 'None'
                    l=0;
                case 'Head'
                    l=1;
                case 'Shoulders'
                    l=2;
                case 'Right Elbow'
                    l=4;
                case 'Left Elbow'
                    l=8;
                case 'Right Wrist'
                    l=6;
                case 'Left Wrist'
                    l=10;
                case 'Hip'
                    l=12;
                case 'Right Knee'
                    l= 14;
                case 'Left Knee'
                    l= 23;
                case 'Right Ankle'
                    l= 16;
                case 'Left Ankle'
                    l= 25;
                case 'Right Heel'
                    l= 18;
                case 'Left Heel'
                    l= 27;
                case 'Right Metatarsus'
                    l=19;
                case 'Left Metatarsus'
                    l=28;
                case 'Right Toes'
                    l=21;
                case 'Left Toes'
                    l=30;
            end
                    
        end

        % Value changed function: AngleDropDown
        function AngleDropDownValueChanged(app, event)
            value = app.AngleDropDown.Value;
            global e
            switch value
                case 'Neck'
                    e=1;
                case 'Right Shoulder'
                    e=2;
                case 'Left Shoulder'
                    e=4;
                case 'Rigth Elbow'
                    e=3;
                case 'Left Elbow'
                    e=5;
                case 'Trunc'
                    e=6;
                case 'Right Hip'
                    e=7;
                case 'Left Hip'
                    e=11;
                case 'Right Knee'
                    e=8;
                case 'Left Knee'
                    e=12;
                case 'Right Foot'
                    e=9;
                case 'Left Foot'
                    e=13;
                case 'Right Toes'
                    e=10;
                case 'Left Toes'
                    e=14;
            end 
        end

        % Value changed function: PositionCheckBox_2
        function PositionCheckBox_2ValueChanged(app, event)
            value = app.PositionCheckBox_2.Value;
            global position
            if position==1
                position =0;
            else
                position = 1;
            end
        end

        % Value changed function: VelocityCheckBox
        function VelocityCheckBoxValueChanged(app, event)
            value = app.VelocityCheckBox.Value;
            global velocity
             if velocity==1
                velocity =0;
            else
                velocity = 1;
            end
        end

        % Value changed function: AccelerationCheckBox
        function AccelerationCheckBoxValueChanged(app, event)
            value = app.AccelerationCheckBox.Value;
            global acceleration
            if acceleration==1
                acceleration =0;
            else
                acceleration = 1;
            end
        end

        % Value changed function: AnglesCheckBox
        function AnglesCheckBoxValueChanged(app, event)
            value = app.AnglesCheckBox.Value;
            global angles
            if angles==1
                angles =0;
            else
                angles = 1;
            end
        end

        % Value changed function: AngularVelocityCheckBox
        function AngularVelocityCheckBoxValueChanged(app, event)
            value = app.AngularVelocityCheckBox.Value;
            global anglevel
            if anglevel==1
                anglevel =0;
            else
                anglevel = 1;
            end
        end

        % Value changed function: AngularAccelerationCheckBox
        function AngularAccelerationCheckBoxValueChanged(app, event)
            value = app.AngularAccelerationCheckBox.Value;
            global angleacc
            if angleacc==1
                angleacc =0;
            else
                angleacc = 1;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.PointsDropDownLabel = uilabel(app.UIFigure);
            app.PointsDropDownLabel.Text = 'Points';
            app.PointsDropDown = uidropdown(app.UIFigure);
            app.PointsDropDown.Items = {'None', 'Head', 'Shoulders', 'Right Elbow', 'Left Elbow', 'Right Wrist', 'Left Wrist', 'Hip', 'Right Knee', 'Left Knee', 'Right Ankle', 'Left Ankle', 'Right Heel', 'Left Heel', 'Right Metatarsus', 'Left Metatarsus', 'Right Toes', 'Left Toes'};
            app.PointsDropDown.ValueChangedFcn = createCallbackFcn(app, @PointsDropDownValueChanged, true);
            app.PointsDropDown.Value = 'None';
            app.AngleDropDownLabel = uilabel(app.UIFigure);
            app.AngleDropDownLabel.Text = 'Angle:';
            app.AngleDropDown = uidropdown(app.UIFigure);
            app.AngleDropDown.Items = {'None', 'Neck', 'Right Shoulder', 'Left Shoulder', 'Right Elbow', 'Left Elbow', 'Trunc', 'Right Hip', 'Left Hip', 'Right Knee', 'Left Knee', 'Right Foot', 'Left Foot', 'Right Toes', 'Left Toes'};
            app.AngleDropDown.ValueChangedFcn = createCallbackFcn(app, @AngleDropDownValueChanged, true);
            app.AngleDropDown.Value = 'None';
            app.WhatgraphicsdoyouwanttoseeLabel = uilabel(app.UIFigure);
            app.WhatgraphicsdoyouwanttoseeLabel.Text = '2. What graphics do you want to see?';
            app.ShowAnimationCheckBox = uicheckbox(app.UIFigure);
            app.ShowAnimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowAnimationCheckBoxValueChanged, true);
            app.ShowAnimationCheckBox.Text = 'Show Animation';
            app.KinematicProjectLabel = uilabel(app.UIFigure);
            app.KinematicProjectLabel.Text = 'Kinematic Project';
            app.EvaluateButton = uibutton(app.UIFigure, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.GetfileButton = uibutton(app.UIFigure, 'push');
            app.GetfileButton.ButtonPushedFcn = createCallbackFcn(app, @GetfileButtonPushed, true);
            app.GetfileButton.Text = 'Get file';
            app.ChoosethemovementyouwanttoanaliseLabel = uilabel(app.UIFigure);
            app.ChoosethemovementyouwanttoanaliseLabel.Text = '1. Choose the movement you want to analise:';
            app.PositionCheckBox_2 = uicheckbox(app.UIFigure);
            app.PositionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PositionCheckBox_2ValueChanged, true);
            app.PositionCheckBox_2.Text = 'Position';
            app.VelocityCheckBox = uicheckbox(app.UIFigure);
            app.VelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @VelocityCheckBoxValueChanged, true);
            app.VelocityCheckBox.Text = 'Velocity';
            app.AccelerationCheckBox = uicheckbox(app.UIFigure);
            app.AccelerationCheckBox.ValueChangedFcn = createCallbackFcn(app, @AccelerationCheckBoxValueChanged, true);
            app.AccelerationCheckBox.Text = 'Acceleration';
            app.AnglesCheckBox = uicheckbox(app.UIFigure);
            app.AnglesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnglesCheckBoxValueChanged, true);
            app.AnglesCheckBox.Text = 'Angles';
            app.AngularVelocityCheckBox = uicheckbox(app.UIFigure);
            app.AngularVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AngularVelocityCheckBoxValueChanged, true);
            app.AngularVelocityCheckBox.Text = 'Angular Velocity';
            app.AngularAccelerationCheckBox = uicheckbox(app.UIFigure);
            app.AngularAccelerationCheckBox.ValueChangedFcn = createCallbackFcn(app, @AngularAccelerationCheckBoxValueChanged, true);
            app.AngularAccelerationCheckBox.Text = 'Angular Acceleration';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Interface_Cinematica_Final()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end